classdef Code_c1948154 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Task1Tab                       matlab.ui.container.Tab
        PlanksEditField_2Label         matlab.ui.control.Label
        PlanksEditField                matlab.ui.control.NumericEditField
        SquaresLabel                   matlab.ui.control.Label
        SquaresEditField               matlab.ui.control.NumericEditField
        EdgeLengthEditField_2Label     matlab.ui.control.Label
        EdgeLengthEditField            matlab.ui.control.NumericEditField
        ValueofPiEditField_2Label      matlab.ui.control.Label
        ValueofPiEditField             matlab.ui.control.NumericEditField
        PlankLengthEditFieldLabel      matlab.ui.control.Label
        PlankLengthEditField           matlab.ui.control.NumericEditField
        SimulateButton                 matlab.ui.control.Button
        Task2Tab                       matlab.ui.container.Tab
        Valueofsqrt2EditField_2Label   matlab.ui.control.Label
        Valueofsqrt2EditField2         matlab.ui.control.NumericEditField
        SquaresEditField_3Label        matlab.ui.control.Label
        SquaresEditField2              matlab.ui.control.NumericEditField
        EdgeLengthEditField_3Label     matlab.ui.control.Label
        EdgeLengthEditField2           matlab.ui.control.NumericEditField
        PlankLengthEditField_2Label    matlab.ui.control.Label
        PlankLengthEditField2          matlab.ui.control.NumericEditField
        SimulateButton2                matlab.ui.control.Button
        PlanksEditField_3Label_2       matlab.ui.control.Label
        PlanksEditField2               matlab.ui.control.NumericEditField
        Task3Tab                       matlab.ui.container.Tab
        RowsColsOfSquaresEditFieldLabel  matlab.ui.control.Label
        RowsColsOfSquaresEditField3    matlab.ui.control.NumericEditField
        NeedlesEditFieldLabel          matlab.ui.control.Label
        NeedlesEditField3              matlab.ui.control.NumericEditField
        NeedleLengthEditFieldLabel     matlab.ui.control.Label
        NeedleLengthEditField3         matlab.ui.control.NumericEditField
        ValueofPiEditField_2Label_2    matlab.ui.control.Label
        ValueofPiEditField3            matlab.ui.control.NumericEditField
        SquareLengthEditFieldLabel     matlab.ui.control.Label
        SquareLengthEditField3         matlab.ui.control.NumericEditField
        SimulateButton3                matlab.ui.control.Button
        Task5Tab                       matlab.ui.container.Tab
        PlanksEditField_3Label_3       matlab.ui.control.Label
        PlanksEditField5               matlab.ui.control.NumericEditField
        SquaresEditField_2Label        matlab.ui.control.Label
        SquaresEditField5              matlab.ui.control.NumericEditField
        AvgValueofPiEditFieldLabel     matlab.ui.control.Label
        AvgValueofPiEditField5         matlab.ui.control.NumericEditField
        PlankLengthEditField_2Label_2  matlab.ui.control.Label
        PlankLengthEditField5          matlab.ui.control.NumericEditField
        EdgeLengthsLabel5              matlab.ui.control.Label
        EL1EditField5                  matlab.ui.control.NumericEditField
        Pi1EditField5                  matlab.ui.control.NumericEditField
        EL2EditField5                  matlab.ui.control.NumericEditField
        Pi2EditField5                  matlab.ui.control.NumericEditField
        EL3EditField5                  matlab.ui.control.NumericEditField
        Pi3EditField5                  matlab.ui.control.NumericEditField
        EL4EditField5                  matlab.ui.control.NumericEditField
        Pi4EditField5                  matlab.ui.control.NumericEditField
        EL5EditField5                  matlab.ui.control.NumericEditField
        Pi5EditField5                  matlab.ui.control.NumericEditField
        PiValueLabel5                  matlab.ui.control.Label
        SimulateButton5                matlab.ui.control.Button
        FontDropDownLabel              matlab.ui.control.Label
        FontDropDown                   matlab.ui.control.DropDown
        ColourDropDownLabel            matlab.ui.control.Label
        ColourDropDown                 matlab.ui.control.DropDown
        FontSizeEditFieldLabel         matlab.ui.control.Label
        FontSizeEditField              matlab.ui.control.NumericEditField
        AdjustButton                   matlab.ui.control.Button
        FileNameEditFieldLabel         matlab.ui.control.Label
        FileNameEditField              matlab.ui.control.EditField
        SaveDataCheckBox               matlab.ui.control.CheckBox
        EnterfilenametoloadblankfornoneEditFieldLabel  matlab.ui.control.Label
        EnterfilenametoloadblankfornoneEditField  matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
    end

% Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function setPlanks(app, Planks, Increment)
            
            ticks = zeros(Planks + 1, 1);
            ylim(app.UIAxes, [0, Planks*Increment]);
            xlim(app.UIAxes, [0, Planks*Increment]);
            
            for y = 1: Planks
                yline(app.UIAxes, y * Increment)
                ticks(y + 1, 1) = y * Increment;
            end
            
            app.UIAxes.XTick = ticks;
            app.UIAxes.XTickLabel = ticks;
            app.UIAxes.YTick = ticks;
            app.UIAxes.YTickLabel = ticks;
            
        end
        
        function setSquares(app, Planks, Increment)
            
            ticks = zeros(Planks + 1, 1);
            ylim(app.UIAxes, [0, Planks*Increment]);
            xlim(app.UIAxes, [0, Planks*Increment]);
            
            for y = 1: Planks
                xline(app.UIAxes, y * Increment)
                ticks(y + 1, 1) = y * Increment;
                yline(app.UIAxes, y * Increment)
                ticks(y + 1, 1) = y * Increment;
            end
            
            app.UIAxes.XTick = ticks;
            app.UIAxes.XTickLabel = ticks;
            app.UIAxes.YTick = ticks;
            app.UIAxes.YTickLabel = ticks;
            
        end
        
        function task5(app, numSquares, Increment)
            
            maxx = xlim(app.UIAxes);
            maxx = maxx(1,2);
            
            maxy = ylim(app.UIAxes);
            maxy = maxy(1,2);
            
            hold(app.UIAxes, 'on');
            
            if isfile(app.EnterfilenametoloadblankfornoneEditField.Value + ".txt")
                 loadedLines = dlmread(app.EnterfilenametoloadblankfornoneEditField.Value + ".txt");
            else
                 loadedLines = [];
            end
            
            Lines = zeros(numSquares*5,3);
            crossvals = zeros(5,1);
            edgeLengths = [app.EL1EditField5.Value, app.EL2EditField5.Value, app.EL3EditField5.Value, app.EL4EditField5.Value, app.EL5EditField5.Value];
            
            for lengths = 1: 5
                
                Lines2 = zeros(numSquares,3);
                
                Crosses = 0;
                
                if numSquares < 101
                    drawLimit = numSquares;
                else
                    drawLimit = round(100 + numSquares*0.01);
                end
                
                if height(loadedLines) < 101
                    drawLimit2 = height(loadedLines);
                else
                    drawLimit2 = round(100 + height(loadedLines)*0.01);
                end
                
                limx = maxx - 2*edgeLengths(lengths);
                limy = maxy - 2*edgeLengths(lengths);
                
                fixedLength = edgeLengths(lengths) / sqrt(2);
                
                for row = 1: height(loadedLines)
                    if row < drawLimit2
                        startPointx = loadedLines(row, 1);
                        startPointy = loadedLines(row, 2);
                        rotation = loadedLines(row, 3);
                        
                        endPointx = startPointx + (fixedLength * cosd(rotation)) - (fixedLength * sind(rotation));
                        endPointy = startPointy + (fixedLength * sind(rotation)) + (fixedLength * cosd(rotation));
                        
                        l2ex = endPointx + (fixedLength * cosd(rotation + 90)) - (fixedLength * sind(rotation + 90));
                        l2ey = endPointy + (fixedLength * sind(rotation + 90)) + (fixedLength * cosd(rotation + 90));
                        
                        l3ex = l2ex + (fixedLength * cosd(rotation + 180)) - (fixedLength * sind(rotation + 180));
                        l3ey = l2ey + (fixedLength * sind(rotation + 180)) + (fixedLength * cosd(rotation + 180));
                        
                        x = [startPointx, endPointx, l2ex, l3ex, startPointx];
                        y = [startPointy, endPointy, l2ey, l3ey, startPointy];
                        
                        color = "-r";
                        
                        if ceil(max(y)/Increment) - ceil(min(y)/Increment) == Increment
                            Crosses = Crosses + 1;
                            color = "-b";
                        end
                        plot(app.UIAxes, x, y, color)
                    else
                        startPointy = loadedLines(row, 2);
                        rotation = loadedLines(row, 3);
                        
                        endPointy = startPointy + (fixedLength * sind(rotation)) + (fixedLength * cosd(rotation));
                        
                        l2ey = endPointy + (fixedLength * sind(rotation + 90)) + (fixedLength * cosd(rotation + 90));
                        
                        l3ey = l2ey + (fixedLength * sind(rotation + 180)) + (fixedLength * cosd(rotation + 180));
                        
                        y = [startPointy, endPointy, l2ey, l3ey, startPointy];
                        
                        if (Increment * ceil(max(y)/Increment)) - (Increment * ceil(min(y)/Increment)) == Increment
                            Crosses = Crosses + 1;
                        end
                    end
                end
                
                for num = 1: drawLimit
                    startPointx = rand(1)*limx + edgeLengths(lengths);
                    startPointy = rand(1)*limy + edgeLengths(lengths);
                    rotation = rand(1)*360;
                    
                    endPointx = startPointx + (fixedLength * cosd(rotation)) - (fixedLength * sind(rotation));
                    endPointy = startPointy + (fixedLength * sind(rotation)) + (fixedLength * cosd(rotation));
                    
                    l2ex = endPointx + (fixedLength * cosd(rotation + 90)) - (fixedLength * sind(rotation + 90));
                    l2ey = endPointy + (fixedLength * sind(rotation + 90)) + (fixedLength * cosd(rotation + 90));
                    
                    l3ex = l2ex + (fixedLength * cosd(rotation + 180)) - (fixedLength * sind(rotation + 180));
                    l3ey = l2ey + (fixedLength * sind(rotation + 180)) + (fixedLength * cosd(rotation + 180));
                    
                    x = [startPointx, endPointx, l2ex, l3ex, startPointx];
                    y = [startPointy, endPointy, l2ey, l3ey, startPointy];
                    
                    Line = [x(1) y(1) rotation];
                    Lines2(num,:) = Line;
                    
                    color = "-r";
                    
                    if ceil(max(y)/Increment) - ceil(min(y)/Increment) == Increment
                        Crosses = Crosses + 1;
                        color = "-b";
                    end
                    plot(app.UIAxes, x, y, color)
                    
                end
                if drawLimit + 1 < numSquares
                    for num = drawLimit + 1: numSquares
                        startPointx = rand(1)*limx + edgeLengths(lengths);
                        startPointy = rand(1)*limy + edgeLengths(lengths);
                        rotation = rand(1)*360;
                        
                        endPointx = startPointx + (fixedLength * cosd(rotation)) - (fixedLength * sind(rotation));
                        endPointy = startPointy + (fixedLength * sind(rotation)) + (fixedLength * cosd(rotation));
                        
                        l2ex = endPointx + (fixedLength * cosd(rotation + 90)) - (fixedLength * sind(rotation + 90));
                        l2ey = endPointy + (fixedLength * sind(rotation + 90)) + (fixedLength * cosd(rotation + 90));
                        
                        l3ex = l2ex + (fixedLength * cosd(rotation + 180)) - (fixedLength * sind(rotation + 180));
                        l3ey = l2ey + (fixedLength * sind(rotation + 180)) + (fixedLength * cosd(rotation + 180));
                        
                        x = [startPointx, endPointx, l2ex, l3ex, startPointx];
                        y = [startPointy, endPointy, l2ey, l3ey, startPointy];
                        
                        Line = [x(1) y(1) rotation];
                        Lines2(num,:) = Line;
                        if (Increment * ceil(max(y)/Increment)) - (Increment * ceil(min(y)/Increment)) == Increment
                            Crosses = Crosses + 1;
                        end
                    end
                end
                Lines = cat(1, Lines, Lines2);
                crossvals(lengths) = Crosses;
            end
            
            if app.SaveDataCheckBox.Value == 1
                Lines = cat(1, Lines, loadedLines);
                dlmwrite(app.FileNameEditField.Value + ".txt", Lines)
            end
            
            numSquares = numSquares + height(loadedLines);
            pivals = zeros(5,1);
            
            for val = 1: 5
                pivals(val) = (edgeLengths(val)*4)/(Increment * crossvals(val)/numSquares);
            end
            
            app.Pi1EditField5.Value = pivals(1);
            app.Pi2EditField5.Value = pivals(2);
            app.Pi3EditField5.Value = pivals(3);
            app.Pi4EditField5.Value = pivals(4);
            app.Pi5EditField5.Value = pivals(5);
            
            app.AvgValueofPiEditField5.Value = mean(pivals);
            
        end

        function task3(app, numNeedles, needleLength, Increment)
            
            maxx = xlim(app.UIAxes);
            maxx = maxx(1,2);
            
            maxy = ylim(app.UIAxes);
            maxy = maxy(1,2);
            
            limx = maxx - 2*needleLength;
            limy = maxy - 2*needleLength;
            
            Lines = zeros(numNeedles,3);
            
            if isfile(app.EnterfilenametoloadblankfornoneEditField.Value + ".txt")
                 loadedLines = dlmread(app.EnterfilenametoloadblankfornoneEditField.Value + ".txt");
            else
                 loadedLines = [];
            end  
            hold(app.UIAxes, 'on');
            
            Crosses = 0;
            Crosses2 = 0;
            fixedLength = needleLength / sqrt(2);
            color = "-r";
            
            if numNeedles < 101
                drawLimit = numNeedles;
            else
                drawLimit = round(100 + numNeedles*0.01);
            end
            
            if height(loadedLines) < 101
                drawLimit2 = height(loadedLines);
            else
                drawLimit2 = round(100 + height(loadedLines)*0.01);
            end
            
            for row = 1: height(loadedLines)
                if row < drawLimit2
                    startPointx = loadedLines(row, 1);
                    startPointy = loadedLines(row, 2);
                    rotation = loadedLines(row, 3);
                    
                    endPointx = startPointx + fixedLength * cosd(rotation) - fixedLength * sind(rotation);
                    endPointy = startPointy + fixedLength * sind(rotation) + fixedLength * cosd(rotation);
                    
                    x = [startPointx, endPointx];
                    y = [startPointy, endPointy];
                    
                    xPlot = [startPointx, endPointx, startPointx];
                    yPlot = [startPointy, endPointy, startPointy];
                    
                    if (Increment * ceil(max(y)/Increment)) - (Increment * ceil(min(y)/Increment)) == Increment
                        Crossesy = true;
                    else
                        Crossesy = false;
                    end
                    if (Increment * ceil(max(x)/Increment)) - (Increment * ceil(min(x)/Increment)) == Increment
                        Crossesx = true;
                    else
                        Crossesx = false;
                    end
                    
                    if Crossesy && Crossesx
                        Crosses2 = Crosses2 + 1;
                        color = "-g";
                    elseif Crossesy
                        Crosses = Crosses + 1;
                        color = "-b";
                    elseif Crossesx
                        Crosses = Crosses + 1;
                        color = "-b";
                    end
                    
                    plot(app.UIAxes, xPlot, yPlot, color);
                
                else
                    startPointx = loadedLines(row, 1);
                    startPointy = loadedLines(row, 2);
                    rotation = loadedLines(row, 3);
                    
                    endPointx = startPointx + fixedLength * cosd(rotation) - fixedLength * sind(rotation);
                    endPointy = startPointy + fixedLength * sind(rotation) + fixedLength * cosd(rotation);
                    
                    x = [startPointx, endPointx];
                    y = [startPointy, endPointy];
                                                            
                    if (Increment * ceil(max(y)/Increment)) - (Increment * ceil(min(y)/Increment)) == Increment
                        Crossesy = true;
                    else
                        Crossesy = false;
                    end
                    if (Increment * ceil(max(x)/Increment)) - (Increment * ceil(min(x)/Increment)) == Increment
                        Crossesx = true;
                    else
                        Crossesx = false;
                    end
                    
                    if Crossesy && Crossesx
                        Crosses2 = Crosses2 + 1;
                    elseif Crossesy
                        Crosses = Crosses + 1;
                    elseif Crossesx
                        Crosses = Crosses + 1;
                    end
                end
            end
            
            for num = 1: drawLimit
                startPointx = rand(1)*limx + needleLength;
                startPointy = rand(1)*limy + needleLength;
                rotation = rand(1)*360;
                
                endPointx = startPointx + fixedLength * cosd(rotation) - fixedLength * sind(rotation);
                endPointy = startPointy + fixedLength * sind(rotation) + fixedLength * cosd(rotation);
                
                x = [startPointx, endPointx];
                y = [startPointy, endPointy];
                
                xPlot = [startPointx, endPointx, startPointx];
                yPlot = [startPointy, endPointy, startPointy];
                
                Line = [xPlot(1) yPlot(1) rotation];
                Lines(num,:) = Line;
                
                if (Increment * ceil(max(y)/Increment)) - (Increment * ceil(min(y)/Increment)) == Increment
                    Crossesy = true;
                else
                    Crossesy = false;
                end
                if (Increment * ceil(max(x)/Increment)) - (Increment * ceil(min(x)/Increment)) == Increment
                    Crossesx = true;
                else
                    Crossesx = false;
                end
                    
                if Crossesy && Crossesx
                    Crosses2 = Crosses2 + 1;
                    color = "-g";
                elseif Crossesy
                    Crosses = Crosses + 1;
                    color = "-b";
                elseif Crossesx
                    Crosses = Crosses + 1;
                    color = "-b";
                end
                
                plot(app.UIAxes, xPlot, yPlot, color);
            end
            
            if drawLimit + 1 < numNeedles
                for num = drawLimit + 1: numNeedles
                    startPointx = rand(1)*limx + needleLength;
                    startPointy = rand(1)*limy + needleLength;
                    rotation = rand(1)*360;
                    
                    endPointx = startPointx + fixedLength * cosd(rotation) - fixedLength * sind(rotation);
                    endPointy = startPointy + fixedLength * sind(rotation) + fixedLength * cosd(rotation);
                    
                    x = [startPointx, endPointx];
                    y = [startPointy, endPointy];
                    
                    xPlot = [startPointx, endPointx, startPointx];
                    yPlot = [startPointy, endPointy, startPointy];
                    
                    Line = [xPlot(1) yPlot(1) rotation];
                    Lines(num,:) = Line;
                    
                    if (Increment * ceil(max(y)/Increment)) - (Increment * ceil(min(y)/Increment)) == Increment
                        Crossesy = true;
                    else
                        Crossesy = false;
                    end
                    if (Increment * ceil(max(x)/Increment)) - (Increment * ceil(min(x)/Increment)) == Increment
                        Crossesx = true;
                    else
                        Crossesx = false;
                    end
                    
                    if Crossesy && Crossesx
                        Crosses2 = Crosses2 + 1;
                    elseif Crossesy
                        Crosses = Crosses + 1;
                    elseif Crossesx
                        Crosses = Crosses + 1;
                    end
                end
            end
            if app.SaveDataCheckBox == 1
                Lines = cat(1, Lines, loadedLines);
                dlmwrite(app.FileNameEditField.Value + ".txt", Lines)
            end
            Pcross = (Crosses + Crosses2)/(numNeedles + height(loadedLines));
            app.ValueofPiEditField3.Value = (4*needleLength*Increment - needleLength*needleLength)/(Pcross*Increment*Increment);
        end        
        
        function task2(app, numSquares, edgeLength, Increment)
        
            maxx = xlim(app.UIAxes);
            maxx = maxx(1,2);
            
            maxy = ylim(app.UIAxes);
            maxy = maxy(1,2);
            
            limx = maxx - 2*edgeLength;
            limy = maxy - 2*edgeLength;
            
            hold(app.UIAxes, 'on');
            
            if isfile(app.EnterfilenametoloadblankfornoneEditField.Value + ".txt")
                 loadedLines = dlmread(app.EnterfilenametoloadblankfornoneEditField.Value + ".txt");
            else
                 loadedLines = [];
            end
            
            Lines = zeros(numSquares,3);
            
            Crosses = 0;
            Crosses2 = 0;
            fixedLength = edgeLength / sqrt(2);
            
            if numSquares < 101
                drawLimit = numSquares;
            else
                drawLimit = round(100 + numSquares*0.01);
            end
            
            if height(loadedLines) < 101
                drawLimit2 = height(loadedLines);
            else
                drawLimit2 = round(100 + height(loadedLines)*0.01);
            end
            
            for row = 1: height(loadedLines)
                if row < drawLimit2
                    startPointx = loadedLines(row, 1);
                    startPointy = loadedLines(row, 2);
                    rotation = loadedLines(row, 3);
                    
                    endPointx = startPointx + fixedLength * cosd(rotation) - fixedLength * sind(rotation);
                    endPointy = startPointy + fixedLength * sind(rotation) + fixedLength * cosd(rotation);
                    
                    l2ex = endPointx + fixedLength * cosd(rotation + 90) - fixedLength * sind(rotation + 90);
                    l2ey = endPointy + fixedLength * sind(rotation + 90) + fixedLength * cosd(rotation + 90);
                    
                    l3ex = l2ex + fixedLength * cosd(rotation + 180) - fixedLength * sind(rotation + 180);
                    l3ey = l2ey + fixedLength * sind(rotation + 180) + fixedLength * cosd(rotation + 180);
                    
                    y = [startPointy, endPointy, l2ey, l3ey];
                    
                    xPlot = [startPointx, endPointx, l2ex, l3ex, startPointx];
                    yPlot = [startPointy, endPointy, l2ey, l3ey, startPointy];
                    
                    color = "-r";
                    
                    if (Increment * ceil(max(y)/Increment)) - (Increment * ceil(min(y)/Increment)) == Increment
                        Crosses = Crosses + 1;
                        color = "-b";
                        
                        %Wrap the index if it leaves when trying to get 2
                        %consecutive points
                        
                        maxyIndex = find(y == max(y));
                        if maxyIndex - 1 == 0
                            loweryIndex = 4;
                        else
                            loweryIndex = maxyIndex - 1;
                        end
                        if maxyIndex + 1 == 5
                            upperyIndex = 1;
                        else
                            upperyIndex = maxyIndex + 1;
                        end
                        
                        loweryVal = y(loweryIndex);
                        yVal = y(maxyIndex);
                        upperyVal = y(upperyIndex);
                        
                        if (Increment * ceil(yVal/Increment)) - (Increment * ceil(loweryVal/Increment)) == Increment
                            if (Increment * ceil(yVal/Increment)) - (Increment * ceil(upperyVal/Increment)) == Increment
                                Crosses2 = Crosses2 + 1;
                                Crosses = Crosses - 1;
                                color = "-g";
                            end
                        end
                        
                        minyIndex = find(y == min(y));
                        if minyIndex - 1 == 0
                            loweryIndex = 4;
                        else
                            loweryIndex = minyIndex - 1;
                        end
                        if minyIndex + 1 == 5
                            upperyIndex = 1;
                        else
                            upperyIndex = minyIndex + 1;
                        end
                        
                        loweryVal = y(loweryIndex);
                        yVal = y(minyIndex);
                        upperyVal = y(upperyIndex);
                        
                        if (Increment * ceil(loweryVal/Increment)) - (Increment * ceil(yVal/Increment)) == Increment
                            if (Increment * ceil(upperyVal/Increment)) - (Increment * ceil(yVal/Increment)) == Increment
                                Crosses2 = Crosses2 + 1;
                                Crosses = Crosses - 1;
                                color = "-g";
                            end
                        end
                    end
                    plot(app.UIAxes, xPlot, yPlot, color);
                
                else
                    startPointy = loadedLines(row, 2);
                    rotation = loadedLines(row, 3);
                    
                    endPointy = startPointy + fixedLength * sind(rotation) + fixedLength * cosd(rotation);
                    
                    l2ey = endPointy + fixedLength * sind(rotation + 90) + fixedLength * cosd(rotation + 90);
                    
                    l3ey = l2ey + fixedLength * sind(rotation + 180) + fixedLength * cosd(rotation + 180);
                    
                    y = [startPointy, endPointy, l2ey, l3ey];
                                        
                    if (Increment * ceil(max(y)/Increment)) - (Increment * ceil(min(y)/Increment)) == Increment
                        Crosses = Crosses + 1;
                        
                        maxyIndex = find(y == max(y));
                        if maxyIndex - 1 == 0
                            loweryIndex = 4;
                        else
                            loweryIndex = maxyIndex - 1;
                        end
                        if maxyIndex + 1 == 5
                            upperyIndex = 1;
                        else
                            upperyIndex = maxyIndex + 1;
                        end
                        
                        loweryVal = y(loweryIndex);
                        yVal = y(maxyIndex);
                        upperyVal = y(upperyIndex);
                        
                        if (Increment * ceil(yVal/Increment)) - (Increment * ceil(loweryVal/Increment)) == Increment
                            if (Increment * ceil(yVal/Increment)) - (Increment * ceil(upperyVal/Increment)) == Increment
                                Crosses2 = Crosses2 + 1;
                                Crosses = Crosses - 1;
                            end
                        end
                        
                        minyIndex = find(y == min(y));
                        if minyIndex - 1 == 0
                            loweryIndex = 4;
                        else
                            loweryIndex = minyIndex - 1;
                        end
                        if minyIndex + 1 == 5
                            upperyIndex = 1;
                        else
                            upperyIndex = minyIndex + 1;
                        end
                        
                        loweryVal = y(loweryIndex);
                        yVal = y(minyIndex);
                        upperyVal = y(upperyIndex);
                        
                        if (Increment * ceil(loweryVal/Increment)) - (Increment * ceil(yVal/Increment)) == Increment
                            if (Increment * ceil(upperyVal/Increment)) - (Increment * ceil(yVal/Increment)) == Increment
                                Crosses2 = Crosses2 + 1;
                                Crosses = Crosses - 1;
                            end
                        end
                    end
                end
            end
        
            for num = 1: drawLimit
                startPointx = rand(1)*limx + edgeLength;
                startPointy = rand(1)*limy + edgeLength;
                rotation = rand(1)*360;
                
                endPointx = startPointx + fixedLength * cosd(rotation) - fixedLength * sind(rotation);
                endPointy = startPointy + fixedLength * sind(rotation) + fixedLength * cosd(rotation);
                
                l2ex = endPointx + fixedLength * cosd(rotation + 90) - fixedLength * sind(rotation + 90);
                l2ey = endPointy + fixedLength * sind(rotation + 90) + fixedLength * cosd(rotation + 90);
                
                l3ex = l2ex + fixedLength * cosd(rotation + 180) - fixedLength * sind(rotation + 180);
                l3ey = l2ey + fixedLength * sind(rotation + 180) + fixedLength * cosd(rotation + 180);
                
                y = [startPointy, endPointy, l2ey, l3ey];
                
                xPlot = [startPointx, endPointx, l2ex, l3ex, startPointx];
                yPlot = [startPointy, endPointy, l2ey, l3ey, startPointy];
                
                Line = [xPlot(1) yPlot(1) rotation];
                Lines(num,:) = Line;
                color = "-r";
                
                if (Increment * ceil(max(y)/Increment)) - (Increment * ceil(min(y)/Increment)) == Increment
                    Crosses = Crosses + 1;
                    color = "-b";
                    
                    %Wrap the index if it leaves when trying to get 2
                    %consecutive points
                    
                    maxyIndex = find(y == max(y));
                    if maxyIndex - 1 == 0
                        loweryIndex = 4;
                    else
                        loweryIndex = maxyIndex - 1;
                    end
                    if maxyIndex + 1 == 5
                        upperyIndex = 1;
                    else
                        upperyIndex = maxyIndex + 1;
                    end
                    
                    loweryVal = y(loweryIndex);
                    yVal = y(maxyIndex);
                    upperyVal = y(upperyIndex);
                    
                    if (Increment * ceil(yVal/Increment)) - (Increment * ceil(loweryVal/Increment)) == Increment
                        if (Increment * ceil(yVal/Increment)) - (Increment * ceil(upperyVal/Increment)) == Increment
                            Crosses2 = Crosses2 + 1;
                            Crosses = Crosses - 1;
                            color = "-g";
                        end
                    end
                    
                    minyIndex = find(y == min(y));
                    if minyIndex - 1 == 0
                        loweryIndex = 4;
                    else
                        loweryIndex = minyIndex - 1;
                    end
                    if minyIndex + 1 == 5
                        upperyIndex = 1;
                    else
                        upperyIndex = minyIndex + 1;
                    end
                    
                    loweryVal = y(loweryIndex);
                    yVal = y(minyIndex);
                    upperyVal = y(upperyIndex);
                    
                    if (Increment * ceil(loweryVal/Increment)) - (Increment * ceil(yVal/Increment)) == Increment
                        if (Increment * ceil(upperyVal/Increment)) - (Increment * ceil(yVal/Increment)) == Increment
                            Crosses2 = Crosses2 + 1;
                            Crosses = Crosses - 1;
                            color = "-g";
                        end
                    end
                end
                plot(app.UIAxes, xPlot, yPlot, color);
            end
            if drawLimit + 1 < numSquares
                for num = drawLimit + 1: numSquares
                    startPointx = rand(1)*limx + edgeLength;
                    startPointy = rand(1)*limy + edgeLength;
                    rotation = rand(1)*360;
                    
                    endPointx = startPointx + fixedLength * cosd(rotation) - fixedLength * sind(rotation);
                    endPointy = startPointy + fixedLength * sind(rotation) + fixedLength * cosd(rotation);
                    
                    l2ex = endPointx + fixedLength * cosd(rotation + 90) - fixedLength * sind(rotation + 90);
                    l2ey = endPointy + fixedLength * sind(rotation + 90) + fixedLength * cosd(rotation + 90);
                    
                    l3ex = l2ex + fixedLength * cosd(rotation + 180) - fixedLength * sind(rotation + 180);
                    l3ey = l2ey + fixedLength * sind(rotation + 180) + fixedLength * cosd(rotation + 180);
                    
                    y = [startPointy, endPointy, l2ey, l3ey];
                    
                    xPlot = [startPointx, endPointx, l2ex, l3ex, startPointx];
                    yPlot = [startPointy, endPointy, l2ey, l3ey, startPointy];
                    
                    Line = [xPlot(1) yPlot(1) rotation];
                    Lines(num,:) = Line;
                    
                    if (Increment * ceil(max(y)/Increment)) - (Increment * ceil(min(y)/Increment)) == Increment
                        Crosses = Crosses + 1;
                        
                        maxyIndex = find(y == max(y));
                        if maxyIndex - 1 == 0
                            loweryIndex = 4;
                        else
                            loweryIndex = maxyIndex - 1;
                        end
                        if maxyIndex + 1 == 5
                            upperyIndex = 1;
                        else
                            upperyIndex = maxyIndex + 1;
                        end
                        
                        loweryVal = y(loweryIndex);
                        yVal = y(maxyIndex);
                        upperyVal = y(upperyIndex);
                        
                        if (Increment * ceil(yVal/Increment)) - (Increment * ceil(loweryVal/Increment)) == Increment
                            if (Increment * ceil(yVal/Increment)) - (Increment * ceil(upperyVal/Increment)) == Increment
                                Crosses2 = Crosses2 + 1;
                                Crosses = Crosses - 1;
                            end
                        end
                        
                        minyIndex = find(y == min(y));
                        if minyIndex - 1 == 0
                            loweryIndex = 4;
                        else
                            loweryIndex = minyIndex - 1;
                        end
                        if minyIndex + 1 == 5
                            upperyIndex = 1;
                        else
                            upperyIndex = minyIndex + 1;
                        end
                        
                        loweryVal = y(loweryIndex);
                        yVal = y(minyIndex);
                        upperyVal = y(upperyIndex);
                        
                        if (Increment * ceil(loweryVal/Increment)) - (Increment * ceil(yVal/Increment)) == Increment
                            if (Increment * ceil(upperyVal/Increment)) - (Increment * ceil(yVal/Increment)) == Increment
                                Crosses2 = Crosses2 + 1;
                                Crosses = Crosses - 1;
                            end
                        end
                    end
                end
            end
            if app.SaveDataCheckBox.Value == 1
                Lines = Lines + loadedLines;
                dlmwrite(app.FileNameEditField.Value + ".txt", Lines)
            end
            app.Valueofsqrt2EditField2.Value = (-Crosses)/(Crosses2) + 2;
        end
        
        function task1(app, numSquares, edgeLength, Increment)
            
            maxx = xlim(app.UIAxes);
            maxx = maxx(1,2);
            
            maxy = ylim(app.UIAxes);
            maxy = maxy(1,2);
            
            limx = maxx - 2*edgeLength;
            limy = maxy - 2*edgeLength;
            
            hold(app.UIAxes, 'on');
            
            if isfile(app.EnterfilenametoloadblankfornoneEditField.Value + ".txt")
                 loadedLines = dlmread(app.EnterfilenametoloadblankfornoneEditField.Value + ".txt");
            else
                 loadedLines = [];
            end
            Lines = zeros(numSquares,3);
            
            Crosses = 0;
            
            fixedLength = edgeLength / sqrt(2);
            
            if numSquares < 101
                drawLimit = numSquares;
            else
                drawLimit = round(100 + numSquares*0.01);
            end
            
            if height(loadedLines) < 101
                drawLimit2 = height(loadedLines);
            else
                drawLimit2 = round(100 + height(loadedLines)*0.01);
            end
            
            for row = 1: height(loadedLines)
                if row < drawLimit2
                    startPointx = loadedLines(row, 1);
                    startPointy = loadedLines(row, 2);
                    rotation = loadedLines(row, 3);
                    
                    endPointx = startPointx + (fixedLength * cosd(rotation)) - (fixedLength * sind(rotation));
                    endPointy = startPointy + (fixedLength * sind(rotation)) + (fixedLength * cosd(rotation));
                        
                    l2ex = endPointx + (fixedLength * cosd(rotation + 90)) - (fixedLength * sind(rotation + 90));
                    l2ey = endPointy + (fixedLength * sind(rotation + 90)) + (fixedLength * cosd(rotation + 90));
                        
                    l3ex = l2ex + (fixedLength * cosd(rotation + 180)) - (fixedLength * sind(rotation + 180));
                    l3ey = l2ey + (fixedLength * sind(rotation + 180)) + (fixedLength * cosd(rotation + 180));
                    
                    x = [startPointx, endPointx, l2ex, l3ex, startPointx];
                    y = [startPointy, endPointy, l2ey, l3ey, startPointy];
                                        
                    color = "-r";
                    
                    if ceil(max(y)/Increment) - ceil(min(y)/Increment) == Increment
                        Crosses = Crosses + 1;
                        color = "-b";
                    end
                    plot(app.UIAxes, x, y, color)
                else
                    startPointy = loadedLines(row, 2);
                    rotation = loadedLines(row, 3);
                    
                    endPointy = startPointy + (fixedLength * sind(rotation)) + (fixedLength * cosd(rotation));
                    
                    l2ey = endPointy + (fixedLength * sind(rotation + 90)) + (fixedLength * cosd(rotation + 90));
                    
                    l3ey = l2ey + (fixedLength * sind(rotation + 180)) + (fixedLength * cosd(rotation + 180));
                    
                    y = [startPointy, endPointy, l2ey, l3ey, startPointy];
                                        
                    if (Increment * ceil(max(y)/Increment)) - (Increment * ceil(min(y)/Increment)) == Increment
                        Crosses = Crosses + 1;
                    end
                end
            end
            
            for num = 1: drawLimit
                startPointx = rand(1)*limx + edgeLength;
                startPointy = rand(1)*limy + edgeLength;
                rotation = rand(1)*360;
                    
                endPointx = startPointx + (fixedLength * cosd(rotation)) - (fixedLength * sind(rotation));
                endPointy = startPointy + (fixedLength * sind(rotation)) + (fixedLength * cosd(rotation));
                    
                l2ex = endPointx + (fixedLength * cosd(rotation + 90)) - (fixedLength * sind(rotation + 90));
                l2ey = endPointy + (fixedLength * sind(rotation + 90)) + (fixedLength * cosd(rotation + 90));
                    
                l3ex = l2ex + (fixedLength * cosd(rotation + 180)) - (fixedLength * sind(rotation + 180));
                l3ey = l2ey + (fixedLength * sind(rotation + 180)) + (fixedLength * cosd(rotation + 180));
                
                x = [startPointx, endPointx, l2ex, l3ex, startPointx];
                y = [startPointy, endPointy, l2ey, l3ey, startPointy];
                
                Line = [x(1) y(1) rotation];
                Lines(num,:) = Line;
                
                color = "-r";
                
                if ceil(max(y)/Increment) - ceil(min(y)/Increment) == Increment
                    Crosses = Crosses + 1;
                    color = "-b";
                end
                plot(app.UIAxes, x, y, color)
                
            end
            if drawLimit + 1 < numSquares
                for num = drawLimit + 1: numSquares
                    startPointx = rand(1)*limx + edgeLength;
                    startPointy = rand(1)*limy + edgeLength;
                    rotation = rand(1)*360;
                    
                    endPointx = startPointx + (fixedLength * cosd(rotation)) - (fixedLength * sind(rotation));
                    endPointy = startPointy + (fixedLength * sind(rotation)) + (fixedLength * cosd(rotation));
                    
                    l2ex = endPointx + (fixedLength * cosd(rotation + 90)) - (fixedLength * sind(rotation + 90));
                    l2ey = endPointy + (fixedLength * sind(rotation + 90)) + (fixedLength * cosd(rotation + 90));
                    
                    l3ex = l2ex + (fixedLength * cosd(rotation + 180)) - (fixedLength * sind(rotation + 180));
                    l3ey = l2ey + (fixedLength * sind(rotation + 180)) + (fixedLength * cosd(rotation + 180));
                    
                    x = [startPointx, endPointx, l2ex, l3ex, startPointx];
                    y = [startPointy, endPointy, l2ey, l3ey, startPointy];
                    
                    Line = [x(1) y(1) rotation];
                    Lines(num,:) = Line;
                    if (Increment * ceil(max(y)/Increment)) - (Increment * ceil(min(y)/Increment)) == Increment
                        Crosses = Crosses + 1;
                    end
                end
            end
            if app.SaveDataCheckBox.Value == 1
                Lines = cat(1, Lines, loadedLines);
                dlmwrite(app.FileNameEditField.Value + ".txt", Lines)
            end
            numSquares = numSquares + height(loadedLines);
            app.ValueofPiEditField.Value = (edgeLength*4)/(Increment * Crosses/numSquares);
            end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            cla(app.UIAxes);
            setPlanks(app, app.PlanksEditField.Value, app.PlankLengthEditField.Value);
            task1(app, app.SquaresEditField.Value, app.EdgeLengthEditField.Value, app.PlankLengthEditField.Value)
            
        end

        % Button pushed function: SimulateButton2
        function SimulateButton2Pushed(app, event)
            cla(app.UIAxes);
            setPlanks(app, app.PlanksEditField2.Value, app.PlankLengthEditField2.Value);
            task2(app, app.SquaresEditField2.Value, app.EdgeLengthEditField2.Value, app.PlankLengthEditField2.Value)
        end

        % Button pushed function: AdjustButton
        function AdjustButtonPushed(app, event)
            %Credit to OP, Jae Heon Kim at:
            %https://uk.mathworks.com/matlabcentral/answers/504775-change-font-size-of-every-components-in-uifigure-app-designer-by-following-the-uifigures-s-size
            %For solution
            
            Font = app.FontDropDown.Value;
            FontSize = app.FontSizeEditField.Value;
            Colour = app.ColourDropDown.Value;
            
            FontToChange = findobj(app.UIFigure, '-property', 'FontName');
            FontSizeToChange = findobj(app.UIFigure, '-property', 'FontSize');
            FontColourToChange = findobj(app.UIFigure, '-property', 'FontColor');
            
            FontCell = {Font};
            FontColourCell = {Colour};
            
            set(FontToChange, {'FontName'}, FontCell);
            set(FontSizeToChange, {'FontSize'}, num2cell(FontSize));
            set(FontColourToChange, {'FontColor'}, FontColourCell);
        end

        % Button pushed function: SimulateButton3
        function SimulateButton3Pushed(app, event)
            cla(app.UIAxes);
            setSquares(app, app.RowsColsOfSquaresEditField3.Value, app.PlankLengthEditField.Value);
            task3(app, app.NeedlesEditField3.Value, app.NeedleLengthEditField3.Value, app.SquareLengthEditField3.Value)
        end

        % Button pushed function: SimulateButton5
        function SimulateButton5Pushed(app, event)
            cla(app.UIAxes);
            setPlanks(app, app.PlanksEditField5.Value, app.PlankLengthEditField5.Value);
            task5(app, app.SquaresEditField5.Value, app.PlankLengthEditField5.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Task1Tab = uitab(app.TabGroup);
            app.Task1Tab.Title = 'Task 1';
            app.PlanksEditField_2Label = uilabel(app.Task1Tab);
            app.PlanksEditField_2Label.Text = 'Planks';
            app.PlanksEditField = uieditfield(app.Task1Tab, 'numeric');
            app.PlanksEditField.Limits = [1 Inf];
            app.PlanksEditField.Value = 5;
            app.SquaresLabel = uilabel(app.Task1Tab);
            app.SquaresLabel.Text = 'Squares ';
            app.SquaresEditField = uieditfield(app.Task1Tab, 'numeric');
            app.SquaresEditField.Limits = [1 Inf];
            app.SquaresEditField.Value = 1000;
            app.EdgeLengthEditField_2Label = uilabel(app.Task1Tab);
            app.EdgeLengthEditField_2Label.Text = 'Edge Length';
            app.EdgeLengthEditField = uieditfield(app.Task1Tab, 'numeric');
            app.EdgeLengthEditField.Limits = [0 Inf];
            app.EdgeLengthEditField.Value = 0.5;
            app.ValueofPiEditField_2Label = uilabel(app.Task1Tab);
            app.ValueofPiEditField_2Label.Text = 'Value of Pi';
            app.ValueofPiEditField = uieditfield(app.Task1Tab, 'numeric');
            app.ValueofPiEditField.Editable = 'off';
            app.PlankLengthEditFieldLabel = uilabel(app.Task1Tab);
            app.PlankLengthEditFieldLabel.Text = 'Plank Length';
            app.PlankLengthEditField = uieditfield(app.Task1Tab, 'numeric');
            app.PlankLengthEditField.Limits = [1 Inf];
            app.PlankLengthEditField.Value = 1;
            app.SimulateButton = uibutton(app.Task1Tab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.Task2Tab = uitab(app.TabGroup);
            app.Task2Tab.Title = 'Task 2';
            app.Valueofsqrt2EditField_2Label = uilabel(app.Task2Tab);
            app.Valueofsqrt2EditField_2Label.Text = 'Value of sqrt 2';
            app.Valueofsqrt2EditField2 = uieditfield(app.Task2Tab, 'numeric');
            app.Valueofsqrt2EditField2.Editable = 'off';
            app.SquaresEditField_3Label = uilabel(app.Task2Tab);
            app.SquaresEditField_3Label.Text = 'Squares';
            app.SquaresEditField2 = uieditfield(app.Task2Tab, 'numeric');
            app.SquaresEditField2.Limits = [1 Inf];
            app.SquaresEditField2.Value = 1000;
            app.EdgeLengthEditField_3Label = uilabel(app.Task2Tab);
            app.EdgeLengthEditField_3Label.Text = 'Edge Length';
            app.EdgeLengthEditField2 = uieditfield(app.Task2Tab, 'numeric');
            app.EdgeLengthEditField2.Limits = [0 1];
            app.EdgeLengthEditField2.Value = 0.5;
            app.PlankLengthEditField_2Label = uilabel(app.Task2Tab);
            app.PlankLengthEditField_2Label.Text = 'Plank Length';
            app.PlankLengthEditField2 = uieditfield(app.Task2Tab, 'numeric');
            app.PlankLengthEditField2.Limits = [1 Inf];
            app.PlankLengthEditField2.Value = 1;
            app.SimulateButton2 = uibutton(app.Task2Tab, 'push');
            app.SimulateButton2.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton2Pushed, true);
            app.SimulateButton2.Text = 'Simulate';
            app.PlanksEditField_3Label_2 = uilabel(app.Task2Tab);
            app.PlanksEditField_3Label_2.Text = 'Planks';
            app.PlanksEditField2 = uieditfield(app.Task2Tab, 'numeric');
            app.PlanksEditField2.Limits = [1 Inf];
            app.PlanksEditField2.Value = 5;
            app.Task3Tab = uitab(app.TabGroup);
            app.Task3Tab.Title = 'Task 3';
            app.RowsColsOfSquaresEditFieldLabel = uilabel(app.Task3Tab);
            app.RowsColsOfSquaresEditFieldLabel.Text = 'Rows + Cols Of Squares';
            app.RowsColsOfSquaresEditField3 = uieditfield(app.Task3Tab, 'numeric');
            app.RowsColsOfSquaresEditField3.Limits = [1 Inf];
            app.RowsColsOfSquaresEditField3.Value = 5;
            app.NeedlesEditFieldLabel = uilabel(app.Task3Tab);
            app.NeedlesEditFieldLabel.Text = 'Needles';
            app.NeedlesEditField3 = uieditfield(app.Task3Tab, 'numeric');
            app.NeedlesEditField3.Limits = [1 Inf];
            app.NeedlesEditField3.Value = 1000;
            app.NeedleLengthEditFieldLabel = uilabel(app.Task3Tab);
            app.NeedleLengthEditFieldLabel.Text = 'Needle Length';
            app.NeedleLengthEditField3 = uieditfield(app.Task3Tab, 'numeric');
            app.NeedleLengthEditField3.Limits = [0 Inf];
            app.NeedleLengthEditField3.Value = 0.5;
            app.ValueofPiEditField_2Label_2 = uilabel(app.Task3Tab);
            app.ValueofPiEditField_2Label_2.Text = 'Value of Pi';
            app.ValueofPiEditField3 = uieditfield(app.Task3Tab, 'numeric');
            app.ValueofPiEditField3.Editable = 'off';
            app.SquareLengthEditFieldLabel = uilabel(app.Task3Tab);
            app.SquareLengthEditFieldLabel.Text = 'Square Length';
            app.SquareLengthEditField3 = uieditfield(app.Task3Tab, 'numeric');
            app.SquareLengthEditField3.Limits = [1 Inf];
            app.SquareLengthEditField3.Value = 1;
            app.SimulateButton3 = uibutton(app.Task3Tab, 'push');
            app.SimulateButton3.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton3Pushed, true);
            app.SimulateButton3.Text = 'Simulate';
            app.Task5Tab = uitab(app.TabGroup);
            app.Task5Tab.Title = 'Task 5';
            app.PlanksEditField_3Label_3 = uilabel(app.Task5Tab);
            app.PlanksEditField_3Label_3.Text = 'Planks';
            app.PlanksEditField5 = uieditfield(app.Task5Tab, 'numeric');
            app.PlanksEditField5.Limits = [1 Inf];
            app.PlanksEditField5.Value = 5;
            app.SquaresEditField_2Label = uilabel(app.Task5Tab);
            app.SquaresEditField_2Label.Text = 'Squares ';
            app.SquaresEditField5 = uieditfield(app.Task5Tab, 'numeric');
            app.SquaresEditField5.Limits = [1 Inf];
            app.SquaresEditField5.Value = 1000;
            app.AvgValueofPiEditFieldLabel = uilabel(app.Task5Tab);
            app.AvgValueofPiEditFieldLabel.Text = 'Avg Value of Pi';
            app.AvgValueofPiEditField5 = uieditfield(app.Task5Tab, 'numeric');
            app.AvgValueofPiEditField5.Editable = 'off';
            app.PlankLengthEditField_2Label_2 = uilabel(app.Task5Tab);
            app.PlankLengthEditField_2Label_2.Text = 'Plank Length';
            app.PlankLengthEditField5 = uieditfield(app.Task5Tab, 'numeric');
            app.PlankLengthEditField5.Limits = [1 Inf];
            app.PlankLengthEditField5.Value = 1;
            app.EdgeLengthsLabel5 = uilabel(app.Task5Tab);
            app.EdgeLengthsLabel5.Text = 'Edge Lengths';
            app.EL1EditField5 = uieditfield(app.Task5Tab, 'numeric');
            app.EL1EditField5.Value = 1;
            app.Pi1EditField5 = uieditfield(app.Task5Tab, 'numeric');
            app.Pi1EditField5.Editable = 'off';
            app.EL2EditField5 = uieditfield(app.Task5Tab, 'numeric');
            app.EL2EditField5.Value = 2;
            app.Pi2EditField5 = uieditfield(app.Task5Tab, 'numeric');
            app.Pi2EditField5.Editable = 'off';
            app.EL3EditField5 = uieditfield(app.Task5Tab, 'numeric');
            app.EL3EditField5.Value = 3;
            app.Pi3EditField5 = uieditfield(app.Task5Tab, 'numeric');
            app.Pi3EditField5.Editable = 'off';
            app.EL4EditField5 = uieditfield(app.Task5Tab, 'numeric');
            app.EL4EditField5.Value = 4;
            app.Pi4EditField5 = uieditfield(app.Task5Tab, 'numeric');
            app.Pi4EditField5.Editable = 'off';
            app.EL5EditField5 = uieditfield(app.Task5Tab, 'numeric');
            app.EL5EditField5.Value = 5;
            app.Pi5EditField5 = uieditfield(app.Task5Tab, 'numeric');
            app.Pi5EditField5.Editable = 'off';
            app.PiValueLabel5 = uilabel(app.Task5Tab);
            app.PiValueLabel5.Text = 'Pi Values';
            app.SimulateButton5 = uibutton(app.Task5Tab, 'push');
            app.SimulateButton5.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton5Pushed, true);
            app.SimulateButton5.Text = 'Simulate';
            app.FontDropDownLabel = uilabel(app.UIFigure);
            app.FontDropDownLabel.Text = 'Font';
            app.FontDropDown = uidropdown(app.UIFigure);
            app.FontDropDown.Items = {'Helvectica', 'Comic Sans MS', 'Arial', 'Times New Roman', 'Impact'};
            app.FontDropDown.Value = 'Helvectica';
            app.ColourDropDownLabel = uilabel(app.UIFigure);
            app.ColourDropDownLabel.Text = 'Colour';
            app.ColourDropDown = uidropdown(app.UIFigure);
            app.ColourDropDown.Items = {'black', 'red', 'blue', 'green', 'cyan'};
            app.ColourDropDown.Value = 'black';
            app.FontSizeEditFieldLabel = uilabel(app.UIFigure);
            app.FontSizeEditFieldLabel.Text = 'Font Size';
            app.FontSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FontSizeEditField.Limits = [12 30];
            app.FontSizeEditField.Value = 12;
            app.AdjustButton = uibutton(app.UIFigure, 'push');
            app.AdjustButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustButtonPushed, true);
            app.AdjustButton.Text = 'Adjust';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Value = 'filename';
            app.SaveDataCheckBox = uicheckbox(app.UIFigure);
            app.SaveDataCheckBox.Text = 'Save Data?';
            app.EnterfilenametoloadblankfornoneEditFieldLabel = uilabel(app.UIFigure);
            app.EnterfilenametoloadblankfornoneEditFieldLabel.Text = 'Enter filename to load (blank for none)';
            app.EnterfilenametoloadblankfornoneEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'A Graph To Estimate Pi/Sqrt(2) Via Buffons Needle')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 5];
            app.UIAxes.YLim = [0 5];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Code_c1948154

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end